classdef StarIDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        StarIDLamp           matlab.ui.control.Lamp
        StarIDLampLabel      matlab.ui.control.Label
        ConnectLamp          matlab.ui.control.Lamp
        ConnectLampLabel     matlab.ui.control.Label
        LogTextArea          matlab.ui.control.TextArea
        SaveLogButton        matlab.ui.control.Button
        StartStarIDButton    matlab.ui.control.Button
        ServerConnectButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property; % Description
        client;
        StarMat;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 0
                app.client = TCPClient('localhost', 10000);
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 1
                app.ConnectLamp.Color = [0 1 0];
            end
        end

        % Button pushed function: StartStarIDButton
        function StartStarIDButtonPushed(app, event)
           if isa(app.client,'tcpclient') == 1
                app.StarMat = read(app.client,app.client.NumBytesAvailable,"uint8");
                % 임시로 1024x1024 픽셀만 되게 하드코딩 함.
                app.StarMat = reshape(app.StarMat,[1024,1024]);
                imshow(app.StarMat, 'parent', app.UIAxes);

               % imwrite(app.StarMat,'MAP.png');
               % app.Image.ImageSource = 'MAP.png';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.ServerConnectButton = uibutton(app.GridLayout, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'ServerConnect';
            app.StartStarIDButton = uibutton(app.GridLayout, 'push');
            app.StartStarIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartStarIDButtonPushed, true);
            app.StartStarIDButton.Text = 'Start StarID';
            app.SaveLogButton = uibutton(app.GridLayout, 'push');
            app.SaveLogButton.Text = 'Save Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.ConnectLampLabel = uilabel(app.GridLayout);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.GridLayout);
            app.StarIDLampLabel = uilabel(app.GridLayout);
            app.StarIDLampLabel.Text = 'StarID';
            app.StarIDLamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarIDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end